classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DeleteButton                    matlab.ui.control.Button
        EnterButton                     matlab.ui.control.Button
        PlottingbuttongroupButtonGroup  matlab.ui.container.ButtonGroup
        equationButton                  matlab.ui.control.RadioButton
        cosineButton                    matlab.ui.control.RadioButton
        sineButton                      matlab.ui.control.RadioButton
        resultantButton                 matlab.ui.control.RadioButton
        xyaxisButton                    matlab.ui.control.RadioButton
        yaxisButton                     matlab.ui.control.RadioButton
        xaxisButton                     matlab.ui.control.RadioButton
        EqualButton                     matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        ErrorConsoleEditField           matlab.ui.control.EditField
        ErrorConsoleEditFieldLabel      matlab.ui.control.Label
        ResultEditField                 matlab.ui.control.EditField
        ResultEditFieldLabel            matlab.ui.control.Label
        yaxisEditField                  matlab.ui.control.EditField
        yaxisEditFieldLabel             matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        xaxisEditField                  matlab.ui.control.EditField
        xaxisEditFieldLabel             matlab.ui.control.Label
        EnterEquationfunxoryxEditField  matlab.ui.control.EditField
        EnterEquationfunxoryxEditFieldLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EnterEquationfunxoryxEditField
        function EnterEquationfunxoryxEditFieldValueChanged(app, event)
            value = app.EnterEquationfunxoryxEditField.Value;
            
        end

        % Value changed function: xaxisEditField
        function xaxisEditFieldValueChanged(app, event)
            value = app.xaxisEditField.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
           changingValue = app.DropDown.Value;% Change EditField2 to the selected sign
           app.EditField.Value = changingValue;
            
        end

        % Value changed function: yaxisEditField
        function yaxisEditFieldValueChanged(app, event)
            value = app.yaxisEditField.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
   % Get values from EditField_2, DropDown, and EditField3

% Extract and convert the first vector
vector1 = app.xaxisEditField.Value;
vector1 = strrep(vector1, '[', '');
vector1 = strrep(vector1, ']', '');
strArray = strsplit(vector1);

% Initialize the numeric vector
numericVector1 = zeros(1, numel(strArray));

% Convert each string element to a number
try
    for i = 1:numel(strArray)
        numericVector1(i) = str2double(strArray{i});
    end
catch
    % Handle the case where conversion fails
    app.ResultEditField.Value = '';
    app.ErrorConsoleEditField.Value = 'Invalid input. Please enter numeric values for the first vector.';
    return;
end

% Get the operator from the DropDown menu
operator = app.DropDown.Value;

% Extract and convert the second vector
vector2 = app.yaxisEditField.Value;
vector2 = strrep(vector2, '[', '');
vector2 = strrep(vector2, ']', '');
strArray = strsplit(vector2);

% Initialize the numeric vector
numericVector2 = zeros(1, numel(strArray));

% Convert each string element to a number
try
    for i = 1:numel(strArray)
        numericVector2(i) = str2double(strArray{i});
    end
catch
    % Handle the case where conversion fails
    app.ResultEditField.Value = '';
    app.ErrorConsoleEditField.Value = 'Invalid input. Please enter numeric values for the second vector.';
    return;
end

% Check if the vectors have the same dimension
if numel(numericVector1) ~= numel(numericVector2)
    app.ResultEditField.Value = '';
    app.ErrorConsoleEditField.Value = 'The two vectors must be of the same dimension';
    return;
end

% Perform the calculation based on the operator
switch operator
    case '+'
        result = numericVector1 + numericVector2;
    case '-'
        result = numericVector1 - numericVector2;
    case '*'
        result = numericVector1 .* numericVector2;
    case '/'
        % Check for division by zero
        if any(numericVector2 == 0)
            app.ResultEditField.Value = '';
            app.ErrorConsoleEditField.Value = 'Division by zero is not allowed.';
            return;
        end
        result = numericVector1 ./ numericVector2;
end

% Check if the result contains NaN (invalid input)
if any(isnan(result))
    app.ErrorConsoleEditField.Value = 'Invalid input. Please enter numeric values.';
    app.ResultEditField.Value = '';
else
    % Display the result
    app.ErrorConsoleEditField.Value = '';
    app.ResultEditField.Value = sprintf('[%s]', num2str(result));
end

        end

        % Value changed function: ErrorConsoleEditField
        function ErrorConsoleEditFieldValueChanged(app, event)
            value = app.ErrorConsoleEditField.Value;
            
        end

        % Value changed function: ResultEditField
        function ResultEditFieldValueChanged(app, event)
            value = app.ResultEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Selection changed function: PlottingbuttongroupButtonGroup
        function PlottingbuttongroupButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottingbuttongroupButtonGroup.SelectedObject;
            
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
               cla(app.UIAxes);

        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
   selectedButton = app.PlottingbuttongroupButtonGroup.SelectedObject;

% Get values from EditField_2, DropDown, and EditField3
vector1 = app.xaxisEditField.Value;
vector1 = strrep(vector1, '[', '');
vector1 = strrep(vector1, ']', '');
strArray1 = strsplit(vector1);

% Initialize the numeric vector
numericVector1 = zeros(1, numel(strArray1));

% Convert each string element to a number
try
    for i = 1:numel(strArray1)
        numericVector1(i) = str2double(strArray1{i});
    end
catch
    app.ResultEditField.Value = '';
    app.ErrorConsoleEditField.Value = 'Invalid input. Please enter numeric values.';
    return;
end

operator = app.DropDown.Value;
vector2 = app.yaxisEditField.Value;
vector2 = strrep(vector2, '[', '');
vector2 = strrep(vector2, ']', '');
strArray2 = strsplit(vector2);

% Initialize the numeric vector
numericVector2 = zeros(1, numel(strArray2));

% Convert each string element to a number
try
    for i = 1:numel(strArray2)
        numericVector2(i) = str2double(strArray2{i});
    end
catch
    app.ResultEditField.Value = '';
    app.ErrorConsoleEditField.Value = 'Invalid input. Please enter numeric values.';
    return;
end

if numel(numericVector1) ~= numel(numericVector2)
    app.ResultEditField.Value = '';
    app.ErrorConsoleEditField.Value = 'The two vectors must be of the same dimension';
    return;
end

% Perform the calculation based on the operator
switch operator
    case '+'
        result = numericVector1 + numericVector2;
    case '-'
        result = numericVector1 - numericVector2;
    case '*'
        result = numericVector1 .* numericVector2;
    case '/'
        result = numericVector1 ./ numericVector2;
end

if any(isnan(result))
    app.ErrorConsoleEditField.Value = 'Invalid input. Please enter numeric values.';
    app.ResultEditField.Value ='';
else
    app.ErrorConsoleEditField.Value = '';
    app.ResultEditField.Value = sprintf('[%s]', num2str(result));
end


% Implement different actions based on the selected button
switch selectedButton
    case app.xaxisButton
        % Implement action for x-axis selected
        plot(app.UIAxes, numericVector1, 'DisplayName', 'X-Axis Data');
        xlabel(app.UIAxes, 'X-Axis');
        ylabel(app.UIAxes, 'Y-Axis');
        legend(app.UIAxes);
    case app.yaxisButton
        % Implement action for y-axis selected
        plot(app.UIAxes, numericVector2, 'DisplayName', 'Y-Axis Data');
        xlabel(app.UIAxes, 'X-Axis');
        ylabel(app.UIAxes, 'Y-Axis');
        legend(app.UIAxes);
    case app.xyaxisButton
        % Implement action for x-y axis selected
        plot(app.UIAxes, numericVector1, numericVector2, 'DisplayName', 'X-Y Axis Data');
        xlabel(app.UIAxes, 'X-Axis');
        ylabel(app.UIAxes, 'Y-Axis');
        legend(app.UIAxes);
    case app.sineButton
        % Implement action for sine button selected
        x = linspace(-2*pi, 2*pi, 100);
        y = sin(x);
        plot(app.UIAxes, x, y, 'DisplayName', 'Sine Function');
        xlabel(app.UIAxes, 'X-Axis');
        ylabel(app.UIAxes, 'Y-Axis');
        legend(app.UIAxes);
    case app.cosineButton
        % Implement action for cosine button selected
        x = linspace(-2*pi, 2*pi, 100);
        y = cos(x);
        plot(app.UIAxes, x, y, 'DisplayName', 'Cosine Function');
        xlabel(app.UIAxes, 'X-Axis');
        ylabel(app.UIAxes, 'Y-Axis');
        legend(app.UIAxes);
    case app.resultantButton
        % Implement action for resultant button selected
        plot(app.UIAxes, result, 'DisplayName', 'Resultant Data');
        xlabel(app.UIAxes, 'X-Axis');
        ylabel(app.UIAxes, 'Y-Axis');
        legend(app.UIAxes);
    case app.equationButton
        % Plot the result
        % Get the equation string from the EditField
        equationString = app.EnterEquationfunxoryxEditField.Value;
equationString = strrep(equationString, 'x', 'numericVector1');
equationString = strrep(equationString, 'y', 'numericVector2');

% Create a function for evaluation
try
    eval(['equation = @(numericVector1, numericVector2) ' equationString ';']);
catch
    app.ResultEditField.Value = '';
    app.ErrorConsoleEditField.Value = 'Error creating the function for evaluation.';
    return;
end

% Generate x values (numericVector1) for plotting
xValues = linspace(min(numericVector1), max(numericVector1), 100);

% Evaluate the equation using xValues and numericVector2
try
    yValues = equation(xValues, numericVector2);
catch
    app.ResultEditField.Value = '';
    app.ErrorConsoleEditField.Value = 'Error evaluating the equation.';
    return;
end
        plot(app.UIAxes, xValues, yValues, 'DisplayName', 'Custom Equation');
        xlabel(app.UIAxes, 'X-Axis');
        ylabel(app.UIAxes, 'Y-Axis');
        legend(app.UIAxes);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Matrix Calculator'};
            app.EnterEquationfunxoryxEditFieldLabel = uilabel(app.UIFigure);
            app.EnterEquationfunxoryxEditFieldLabel.Text = 'Enter Equation fun(x) or y(x)';
            app.EnterEquationfunxoryxEditField = uieditfield(app.UIFigure, 'text');
            app.EnterEquationfunxoryxEditField.ValueChangedFcn = createCallbackFcn(app, @EnterEquationfunxoryxEditFieldValueChanged, true);
            app.xaxisEditFieldLabel = uilabel(app.UIFigure);
            app.xaxisEditFieldLabel.Text = 'x-axis';
            app.xaxisEditField = uieditfield(app.UIFigure, 'text');
            app.xaxisEditField.ValueChangedFcn = createCallbackFcn(app, @xaxisEditFieldValueChanged, true);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.yaxisEditFieldLabel = uilabel(app.UIFigure);
            app.yaxisEditFieldLabel.Text = 'y-axis';
            app.yaxisEditField = uieditfield(app.UIFigure, 'text');
            app.yaxisEditField.ValueChangedFcn = createCallbackFcn(app, @yaxisEditFieldValueChanged, true);
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'text');
            app.ResultEditField.ValueChangedFcn = createCallbackFcn(app, @ResultEditFieldValueChanged, true);
            app.ErrorConsoleEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorConsoleEditFieldLabel.Text = 'Error Console';
            app.ErrorConsoleEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorConsoleEditField.ValueChangedFcn = createCallbackFcn(app, @ErrorConsoleEditFieldValueChanged, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'+', '-', '*', '/'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '+';
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = 'Equal';
            app.PlottingbuttongroupButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottingbuttongroupButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottingbuttongroupButtonGroupSelectionChanged, true);
            app.PlottingbuttongroupButtonGroup.Title = 'Plotting button group';
            app.xaxisButton = uiradiobutton(app.PlottingbuttongroupButtonGroup);
            app.xaxisButton.Text = 'x axis';
            app.xaxisButton.Value = true;
            app.yaxisButton = uiradiobutton(app.PlottingbuttongroupButtonGroup);
            app.yaxisButton.Text = 'y axis';
            app.xyaxisButton = uiradiobutton(app.PlottingbuttongroupButtonGroup);
            app.xyaxisButton.Text = 'x y axis';
            app.resultantButton = uiradiobutton(app.PlottingbuttongroupButtonGroup);
            app.resultantButton.Text = 'resultant';
            app.sineButton = uiradiobutton(app.PlottingbuttongroupButtonGroup);
            app.sineButton.Text = 'sine';
            app.cosineButton = uiradiobutton(app.PlottingbuttongroupButtonGroup);
            app.cosineButton.Text = 'cosine';
            app.equationButton = uiradiobutton(app.PlottingbuttongroupButtonGroup);
            app.equationButton.Text = 'equation';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end