classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EqualButton                 matlab.ui.control.Button
        DropDown                    matlab.ui.control.DropDown
        DropDownLabel               matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        EditField5                  matlab.ui.control.EditField
        EditField4                  matlab.ui.control.NumericEditField
        EditField3                  matlab.ui.control.NumericEditField
        EditField2                  matlab.ui.control.EditField
        EditField_2                 matlab.ui.control.NumericEditField
        ErrorConsoleEditField       matlab.ui.control.EditField
        ErrorConsoleEditFieldLabel  matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ErrorConsoleEditField
        function ErrorConsoleEditFieldValueChanged(app, event)
            value = app.ErrorConsoleEditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end

        % Value changing function: EditField2
        function EditField2ValueChanging(app, event)
           changingValue = app.DropDown.Value; % Change EditField2 to the selected sign
           app.EditField2.Value = changingValue;
        end

        % Value changed function: EditField5
        function EditField5ValueChanged(app, event)
              value = app.EditField5.Value;

            
        end

        % Value changed function: EditField4
        function EditField4ValueChanged(app, event)
            
            % END: abpxx6d04wxr
            
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
           changingValue = app.DropDown.Value% Change EditField2 to the selected sign
           app.EditField2.Value = changingValue;
        end

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
             % Get values from EditField_2, DropDown, and EditField3
        operand1 = app.EditField_2.Value;
        operator = app.DropDown.Value;
        operand2 = app.EditField3.Value;
        flag=0;

        % Perform the calculation based on the operator
          switch operator
            case '+'
                result = operand1 + operand2;
            case '-'
                result = operand1 - operand2;
            case '*'
                result = operand1 * operand2;
            case '/'
                if operand2 ~= 0
                    result = operand1 / operand2;
                else
                    % Handle division by zero error
                    result = NaN;
                    
                     flag=1;


                end
            otherwise
                % Handle unsupported operator error
                result = NaN;
                app.ErrorConsoleEditField.Value = 'Error: Unsupported operator';

          end

        % Update the value of EditField4 with the calculated result
         switch(flag)
             case 0
              app.EditField4.Value = result;
             otherwise
              app.ErrorConsoleEditField.Value = ['Error: Division by zero' ...
                        ''];
         end

                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '         Simple Calculator';
            app.ErrorConsoleEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorConsoleEditFieldLabel.Text = 'Error Console';
            app.ErrorConsoleEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorConsoleEditField.ValueChangedFcn = createCallbackFcn(app, @ErrorConsoleEditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 3;
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.ValueChangingFcn = createCallbackFcn(app, @EditField2ValueChanging, true);
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.ValueChangedFcn = createCallbackFcn(app, @EditField4ValueChanged, true);
            app.EditField5 = uieditfield(app.UIFigure, 'text');
            app.EditField5.ValueChangedFcn = createCallbackFcn(app, @EditField5ValueChanged, true);
            app.EditField5.Value = '=';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Arithmetic operation'};
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'+', '-', '*', '/'};
            app.DropDown.Editable = 'on';
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.Value = '-';
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = 'Equal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end