classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        EditField_3     matlab.ui.control.EditField
        EditField_2     matlab.ui.control.EditField
        GetImageButton  matlab.ui.control.Button
        TextArea        matlab.ui.control.TextArea
        TextAreaLabel   matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            val = app.EditField_3.Value;
            val2=str2double(val);
            if isnan(val2)
                  % Clear the plot
                cla(app.UIAxes);
                % Display an error in EditField_2 for non-numeric input
                app.EditField_2.Value = 'Error: Enter a numeric value.';
               
            elseif isnumeric(val2) && (val2 <= 60)
                % Clear the plot
                cla(app.UIAxes);
                
                % Display a specific image on the plot
                imshow('happy.JPG', 'Parent', app.UIAxes);
                app.EditField_2.Value = '';

            else
                  % Clear the plot
                  cla(app.UIAxes);
                  
                % Display a specific image on the plot
                imshow('sad.JPG', 'Parent', app.UIAxes);
                app.EditField_2.Value = '';
            end
        end

        % Value changing function: EditField_2
        function EditField_2ValueChanging(app, event)
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'below 60 -> happy   :)'; 'above 60 ->  sad     :('};
            app.GetImageButton = uibutton(app.UIFigure, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'Get Image';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @EditField_2ValueChanging, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end